classdef Q_Measurement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RawTrans                        matlab.ui.control.UIAxes
        RawMzi                          matlab.ui.control.UIAxes
        FFT                             matlab.ui.control.UIAxes
        Filter_plot                     matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        Label_2                         matlab.ui.control.Label
        Step1DetermineChannelPanel      matlab.ui.container.Panel
        TransDropDownLabel              matlab.ui.control.Label
        TransDropDown                   matlab.ui.control.DropDown
        MZIDropDownLabel                matlab.ui.control.Label
        MZIDropDown                     matlab.ui.control.DropDown
        Step2TelltheFSRofyourMZIPanel   matlab.ui.container.Panel
        FSREditFieldLabel               matlab.ui.control.Label
        FSREditField                    matlab.ui.control.NumericEditField
        UnitMhzLabel                    matlab.ui.control.Label
        Mhzis106hzLabel                 matlab.ui.control.Label
        Step3SetWavelengthPanel         matlab.ui.container.Panel
        lambdaEditFieldLabel            matlab.ui.control.Label
        lambdaEditField                 matlab.ui.control.NumericEditField
        UnitnmLabel                     matlab.ui.control.Label
        Step4ThresholdPanel             matlab.ui.container.Panel
        thresholdEditFieldLabel         matlab.ui.control.Label
        thresholdEditField              matlab.ui.control.NumericEditField
        Step5Corr_typePanel             matlab.ui.container.Panel
        CorrectionDropDownLabel         matlab.ui.control.Label
        CorrectionDropDown              matlab.ui.control.DropDown
        Step6Cutoff_freqPanel           matlab.ui.container.Panel
        CutoffFrequencyEditFieldLabel   matlab.ui.control.Label
        CutoffFrequencyEditField        matlab.ui.control.NumericEditField
        UnithzLabel_2                   matlab.ui.control.Label
        Step15FFTyourdataoptionalPanel  matlab.ui.container.Panel
        FFTButton                       matlab.ui.control.Button
        IFFTButton                      matlab.ui.control.Button
        LowerEditFieldLabel             matlab.ui.control.Label
        LowerEditField                  matlab.ui.control.NumericEditField
        UpperEditFieldLabel             matlab.ui.control.Label
        UpperEditField                  matlab.ui.control.NumericEditField
        DisplayedCapturedDataButton     matlab.ui.control.Button
        CaptureButton                   matlab.ui.control.Button
        UseFFTdataforQCheckBox          matlab.ui.control.CheckBox
        Step0OutputFilePathPanel        matlab.ui.container.Panel
        FolderPathEditFieldLabel        matlab.ui.control.Label
        FolderPathEditField             matlab.ui.control.EditField
        Sub1EditFieldLabel              matlab.ui.control.Label
        Sub1EditField                   matlab.ui.control.EditField
        Sub2EditFieldLabel              matlab.ui.control.Label
        Sub2EditField                   matlab.ui.control.EditField
        RunButton                       matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        DataExtractedFromOscilloscopeLabel  matlab.ui.control.Label
        FFTforfilteroptionalseeStep15Label  matlab.ui.control.Label
        ResultTextAreaLabel             matlab.ui.control.Label
        ResultTextArea                  matlab.ui.control.TextArea
        KnownIssueTextAreaLabel         matlab.ui.control.Label
        KnownIssueTextArea              matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        trans %raw trans data
        mzi %raw mzi data
        phi %FFT y-data
        f   %FFT x-data
        clean
        osc %your oscilloscope
    end
    
    methods (Access = private)     
        function [W_shifted, f] = transform(app,trans, samplerate)
             % Check if samplerate is provided, if not, use default 2e9
            if nargin < 3
                samplerate = 2e9; % Default sampling rate
            end
            Fs = samplerate; % Sampling rate in Hz
            N = length(trans); % Number of points in your signal
            df = Fs / N; % Frequency resolution
            f = (-N/2:N/2-1) * df; % Frequency vector centered at 0
            W = fft(trans);
            W_shifted = fftshift(W); % Shift the zero-frequency component to the center of the spectrum
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize the oscilloscope object
            addpath('.');
            
        end

        % Button pushed function: DisplayedCapturedDataButton
        function DisplayCaptured(app, event)
            %Make sure the osc is connected
            if strcmp(app.osc.Status, 'closed')
                try 
                    connect(app.osc);
                    if strcmp(app.osc.Status, 'open')
                        a = 1;
                        % Print 'Connected' in the textbox/label
                        %app.ResultTextArea.Value = [app.ResultTextArea.Value; {'OscResult: Connected'}];
                    else
                        % Print 'Not Connected' in the textbox/label
                       app.ResultTextArea.Value = [app.ResultTextArea.Value; {'Result: Not Connected'}];
                    end
    
                catch ME
                    % Display the error message in the textbox/label
                     app.ResultTextArea.Value = [app.ResultTextArea.Value; {ME.message}];
                     return
                end
            end
            
    
            %Capture Data 
            [w,e] = readWaveform(app.osc);
        
            app.trans = w;
            app.mzi = e;
            % Plot 'trans' on app.RawTrans axes
            plot(app.RawTrans, app.trans);
            
            ylabel(app.RawTrans, 'Voltage');
            title(app.RawTrans, 'Trans');
            
            % Plot 'mzi' on app.RawMzi axes
            plot(app.RawMzi, app.mzi);
            xlabel(app.RawMzi, 'Sample');
            ylabel(app.RawMzi, 'Voltage');
            title(app.RawMzi, 'MZI');
        end

        % Button pushed function: FFTButton
        function fourier(app, event)

            if isempty(app.trans)
                app.ResultTextArea.Value = [app.ResultTextArea.Value; {'Press Display'}];
                return 
            end

            % Perform FFT
            [app.phi,  app.f] = app.transform(app.trans); % Call your FFT function, assuming 'w' is your signal
            
            % Plot the Fourier Transform result on the designated UI Axes (app.FFT)
            plot(app.FFT, app.f, abs(app.phi));
            title(app.FFT, 'Frequency Domain');
            xlabel(app.FFT, 'Frequency (Hz)');
            ylabel(app.FFT, 'Magnitude');
             
            %adjust the size of the figure displayed
            app.FFT.XLim = [0.03*min(app.f) , 0.03*max(app.f) ];
            % Only imposed a y min
            currentYLim = app.FFT.YLim; % This gives you a 2-element vector [ymin, ymax]
            currentYMax = currentYLim(2);
            % Now, set the YLim property with your desired ymin and the retrieved ymax
            app.FFT.YLim = [-0.1*currentYMax, currentYMax];
        end

        % Button pushed function: IFFTButton
        function inverseFFT(app, event)
            
            temp = app.phi;
            
            lower = app.LowerEditField.Value;
            upper = app.UpperEditField.Value;

            temp(app.f > upper | app.f<lower) = 0;

            app.clean=ifft(ifftshift(temp), 'symmetric');

            cleandata = app.clean;
            %save('cleaned_app.mat','cleandata');

            plot(app.Filter_plot, app.trans, 'b-', 'LineWidth', 1.5); % Plot 'app.w'
            hold(app.Filter_plot, 'on'); % Hold on for multiple plots
            plot(app.Filter_plot, cleandata, 'r--', 'LineWidth', 1.5); % Plot 'app.clean'
            
            % Setting the legend, labels, and title
            legend(app.Filter_plot, 'Raw Data', 'Filtered Data', 'Location', 'best');
            xlabel(app.Filter_plot, 'sample');
            ylabel(app.Filter_plot, 'voltage');
           
            % Grid and font size adjustment
            grid(app.Filter_plot, 'on');
            set(app.Filter_plot, 'FontSize', 12);
            hold(app.Filter_plot, 'off'); 

        end

        % Button pushed function: RunButton
        function run(app, event)
%%%
%this function will
%connect the osc
%capture the data
%fit the capture data using Q_trace_fit class
%save it to the designated path
%%%

        %For osc, please look at this website   
        %https://www.mathworks.com/help/instrument/oscilloscope.html

        %Make sure the osc is connected
        instrreset;
        app.osc = Infiniium('USB0::0x2A8D::0x904E::MY54200105::INSTR');
            %app.osc.Resource = 'USB0::0x2A8D::0x0396::CN61297440::0::INSTR';%UCSB
        app.osc.connect;
        [time,w] = app.osc.readtrace(1, 500000);
        [~,e] = app.osc.readtrace(2, 500000);

        deg1 = app.Sub1EditField.Value;
        deg2 = app.Sub2EditField.Value;
        basePath=app.FolderPathEditField.Value; 
        MZI_FSR = app.FSREditField.Value;
        lambda = app.lambdaEditField.Value; 
        threshold= app.thresholdEditField.Value ; 
        corr_type= app.CorrectionDropDown.Value;
        cutoff_freq= app.CutoffFrequencyEditField.Value;

        %{
        transData = load("C:\Users\Eric\Downloads\Q_keysight\KeySight DSOX1204G\todaytrans.mat");
        mzicaltechData = load("C:\Users\Eric\Downloads\Q_keysight\KeySight DSOX1204G\mzitoday(caltech).mat");
        w = transData.w'; 
        e = mzicaltechData.e';
        %}
        corr_type_brother = corr_type;
        if strcmp(corr_type_brother, ' ')
            % Assuming you want to default to a generic name or handle differently
            corr_type_brother = 'no_correction';
        end

        jack = sprintf('data_%s',corr_type_brother );
        inherent = fullfile(basePath, deg1,deg2,jack);
        if ~exist(inherent, 'dir')
            mkdir(inherent);
        end
        mziname = sprintf('osc_mzi_%.2fMHz.mat', MZI_FSR);
        save(fullfile(inherent,'osc_trans.mat'),'w');
        save(fullfile(inherent,mziname),'e');
        cla(app.RawTrans, 'reset');
        cla(app.RawMzi, 'reset');
        cla(app.UIAxes1, 'reset');
        cla(app.UIAxes2, 'reset');
        cla(app.UIAxes3, 'reset');
        % Plot 'trans' on app.RawTrans axes
        plot(app.RawTrans, w);
        
        ylabel(app.RawTrans, 'Voltage');
        title(app.RawTrans, 'Trans');
        
        % Plot 'mzi' on app.RawMzi axes
        plot(app.RawMzi, e);
        xlabel(app.RawMzi, 'Sample');
        ylabel(app.RawMzi, 'Voltage');
        title(app.RawMzi, 'MZI');

        %Do the Q_fitting

        Q = Cao(w,e,MZI_FSR,lambda,threshold,corr_type,cutoff_freq);

        %display the result 
        Q.plot_Q(1,app.UIAxes1);
        Q.plot_trace_stat(app.UIAxes2); 
        Q.plot_Q_stat(app.UIAxes3);
        app.UIAxes1.Title.FontSize = 18;

        
        %save it 

        
        folderName = fullfile(basePath, deg1,deg2 );
        if ~exist(folderName, 'dir')
            mkdir(folderName);
        end
        
        % Filename base for dynamic naming
        filenameBase = 'Q';
        
        % Temporarily generate and save figure for plot_Q with dynamic filename
        figQTemp = figure;
        axQTemp = axes(figQTemp);
        Q.plot_Q(1, axQTemp); % Ensure plot_Q can work with standard axes
        dynamicFilename = sprintf('%s;%s_lambda=%.2fnm_0=%.4gM', deg2, filenameBase, lambda, Q.modeQ0(1));
        fullFilePathQ = fullfile(fullfile(basePath, deg1), [dynamicFilename, '.fig']); % Full file path
        saveas(figQTemp, fullFilePathQ); % Save the figure
        close(figQTemp); % Close the temporary figure
        
        % Temporarily generate and save figure for plot_trace_stat
        figTraceTemp = figure;
        axTraceTemp = axes(figTraceTemp);
        Q.plot_trace_stat(axTraceTemp); % Ensure plot_trace_stat can work with standard axes
        savefig(figTraceTemp, fullfile(folderName, 'Trace.fig')); % Save the figure
        close(figTraceTemp); % Close the temporary figure
        
        % Temporarily generate and save figure for plot_Q_stat
        figQstatTemp = figure;
        axQstatTemp = axes(figQstatTemp);
        Q.plot_Q_stat(axQstatTemp); % Ensure plot_Q_stat can work with standard axes
        savefig(figQstatTemp, fullfile(folderName, 'Qstat.fig')); % Save the figure
        close(figQstatTemp); % Close the temporary figure

        %osc&Q_analysis data
        corr_type = app.CorrectionDropDown.Value;

        % Handle the 'No Correction' case
        if strcmp(corr_type, ' ')
            % Assuming you want to default to a generic name or handle differently
            corr_type = 'no_correction';
        end

        save(fullfile(inherent,'Q_analysis.mat'),'Q');
        %{
        %record the para 
        para = sprintf('Para: %.3f MHz; %.3f nm; %.3f ; %s ; %.3f', MZI_FSR, lambda, threshold, corr_type, cutoff_freq);
        paraname =  fullfile(basePath, deg1,deg2,para);
        if ~exist(paraname, 'dir')
            mkdir(paraname);
        end
        %}

        % Update the app's text area
        info = sprintf('Q0_max = %.4g M, Saved to path: %s', Q.modeQ0(1), folderName);
        app.ResultTextArea.Value = [app.ResultTextArea.Value; {info}];


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RawTrans = uiaxes(app.UIFigure);
            title(app.RawTrans, 'Trans')
            xlabel(app.RawTrans, '')
            ylabel(app.RawTrans, 'voltage')
            zlabel(app.RawTrans, 'Z')
            app.RawMzi = uiaxes(app.UIFigure);
            title(app.RawMzi, 'MZI')
            xlabel(app.RawMzi, 'sample')
            ylabel(app.RawMzi, 'voltage')
            zlabel(app.RawMzi, 'Z')
            app.FFT = uiaxes(app.UIFigure);
            title(app.FFT, 'frequency domain')
            xlabel(app.FFT, 'f')
            ylabel(app.FFT, 'voltage')
            zlabel(app.FFT, 'Z')
            app.Filter_plot = uiaxes(app.UIFigure);
            title(app.Filter_plot, 'Filtered Trans')
            xlabel(app.Filter_plot, 'X')
            ylabel(app.Filter_plot, 'Y')
            zlabel(app.Filter_plot, 'Z')
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Q Measurement';
            app.Step1DetermineChannelPanel = uipanel(app.UIFigure);
            app.Step1DetermineChannelPanel.Title = 'Step 1: Determine Channel';
            app.TransDropDownLabel = uilabel(app.Step1DetermineChannelPanel);
            app.TransDropDownLabel.Text = 'Trans';
            app.TransDropDown = uidropdown(app.Step1DetermineChannelPanel);
            app.TransDropDown.Items = {'CH 1', 'CH 2', 'CH 3', 'CH 4'};
            app.TransDropDown.ItemsData = {'1', '2', '3', '4'};
            app.TransDropDown.Value = '2';
            app.MZIDropDownLabel = uilabel(app.Step1DetermineChannelPanel);
            app.MZIDropDownLabel.Text = 'MZI';
            app.MZIDropDown = uidropdown(app.Step1DetermineChannelPanel);
            app.MZIDropDown.Items = {'CH 1', 'CH 2', 'CH 3', 'CH 4'};
            app.MZIDropDown.ItemsData = {'1', '2', '3', '4'};
            app.MZIDropDown.Value = '3';
            app.Step2TelltheFSRofyourMZIPanel = uipanel(app.UIFigure);
            app.Step2TelltheFSRofyourMZIPanel.Title = 'Step 2: Tell the FSR of your MZI';
            app.FSREditFieldLabel = uilabel(app.Step2TelltheFSRofyourMZIPanel);
            app.FSREditFieldLabel.Text = 'FSR ';
            app.FSREditField = uieditfield(app.Step2TelltheFSRofyourMZIPanel, 'numeric');
            app.FSREditField.Value = 11.85;
            app.UnitMhzLabel = uilabel(app.Step2TelltheFSRofyourMZIPanel);
            app.UnitMhzLabel.Text = 'Unit: Mhz';
            app.Mhzis106hzLabel = uilabel(app.Step2TelltheFSRofyourMZIPanel);
            app.Mhzis106hzLabel.Text = 'Mhz is 10^6 hz';
            app.Step3SetWavelengthPanel = uipanel(app.UIFigure);
            app.Step3SetWavelengthPanel.Title = 'Step 3: Set Wavelength';
            app.lambdaEditFieldLabel = uilabel(app.Step3SetWavelengthPanel);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.Step3SetWavelengthPanel, 'numeric');
            app.lambdaEditField.Value = 976;
            app.UnitnmLabel = uilabel(app.Step3SetWavelengthPanel);
            app.UnitnmLabel.Text = 'Unit: nm';
            app.Step4ThresholdPanel = uipanel(app.UIFigure);
            app.Step4ThresholdPanel.Title = 'Step 4: Threshold';
            app.thresholdEditFieldLabel = uilabel(app.Step4ThresholdPanel);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.Step4ThresholdPanel, 'numeric');
            app.thresholdEditField.Value = 0.95;
            app.Step5Corr_typePanel = uipanel(app.UIFigure);
            app.Step5Corr_typePanel.Title = 'Step 5: Corr_type';
            app.CorrectionDropDownLabel = uilabel(app.Step5Corr_typePanel);
            app.CorrectionDropDownLabel.Text = 'Correction';
            app.CorrectionDropDown = uidropdown(app.Step5Corr_typePanel);
            app.CorrectionDropDown.Items = {'No Correction', 'fano', 'split', 'oscmzi', 'mzi', 'fanomzi', 'splitmzi', 'osc'};
            app.CorrectionDropDown.ItemsData = {' ', 'fano', 'split', 'oscmzi', 'mzi', 'fanomzi', 'splitmzi', 'osc'};
            app.CorrectionDropDown.Value = ' ';
            app.Step6Cutoff_freqPanel = uipanel(app.UIFigure);
            app.Step6Cutoff_freqPanel.Title = 'Step 6: Cutoff_freq';
            app.CutoffFrequencyEditFieldLabel = uilabel(app.Step6Cutoff_freqPanel);
            app.CutoffFrequencyEditFieldLabel.Text = 'Cutoff Frequency';
            app.CutoffFrequencyEditField = uieditfield(app.Step6Cutoff_freqPanel, 'numeric');
            app.UnithzLabel_2 = uilabel(app.Step6Cutoff_freqPanel);
            app.UnithzLabel_2.Text = 'Unit: hz';
            app.Step15FFTyourdataoptionalPanel = uipanel(app.UIFigure);
            app.Step15FFTyourdataoptionalPanel.Title = 'Step 1.5: FFT your data(optional)';
            app.FFTButton = uibutton(app.Step15FFTyourdataoptionalPanel, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @fourier, true);
            app.FFTButton.Text = 'FFT';
            app.IFFTButton = uibutton(app.Step15FFTyourdataoptionalPanel, 'push');
            app.IFFTButton.ButtonPushedFcn = createCallbackFcn(app, @inverseFFT, true);
            app.IFFTButton.Text = 'IFFT';
            app.LowerEditFieldLabel = uilabel(app.Step15FFTyourdataoptionalPanel);
            app.LowerEditFieldLabel.Text = 'Lower ';
            app.LowerEditField = uieditfield(app.Step15FFTyourdataoptionalPanel, 'numeric');
            app.LowerEditField.Value = -100000;
            app.UpperEditFieldLabel = uilabel(app.Step15FFTyourdataoptionalPanel);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.Step15FFTyourdataoptionalPanel, 'numeric');
            app.UpperEditField.Value = 100000;
            app.DisplayedCapturedDataButton = uibutton(app.Step15FFTyourdataoptionalPanel, 'push');
            app.DisplayedCapturedDataButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayCaptured, true);
            app.DisplayedCapturedDataButton.Text = 'Displayed Captured Data';
            app.CaptureButton = uibutton(app.Step15FFTyourdataoptionalPanel, 'push');
            app.CaptureButton.Text = 'Capture';
            app.UseFFTdataforQCheckBox = uicheckbox(app.Step15FFTyourdataoptionalPanel);
            app.UseFFTdataforQCheckBox.Text = 'Use FFT data for Q';
            app.Step0OutputFilePathPanel = uipanel(app.UIFigure);
            app.Step0OutputFilePathPanel.Title = 'Step 0: Output File Path';
            app.FolderPathEditFieldLabel = uilabel(app.Step0OutputFilePathPanel);
            app.FolderPathEditFieldLabel.Text = 'Folder Path';
            app.FolderPathEditField = uieditfield(app.Step0OutputFilePathPanel, 'text');
            app.FolderPathEditField.Value = 'Z:\Haojing\01 - FHD ring - Q measurement';
            app.Sub1EditFieldLabel = uilabel(app.Step0OutputFilePathPanel);
            app.Sub1EditFieldLabel.Text = 'Sub1';
            app.Sub1EditField = uieditfield(app.Step0OutputFilePathPanel, 'text');
            app.Sub1EditField.Value = 'test';
            app.Sub2EditFieldLabel = uilabel(app.Step0OutputFilePathPanel);
            app.Sub2EditFieldLabel.Text = 'Sub2';
            app.Sub2EditField = uieditfield(app.Step0OutputFilePathPanel, 'text');
            app.Sub2EditField.Value = 'garbage';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @run, true);
            app.RunButton.Text = 'Run';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '';
            app.DataExtractedFromOscilloscopeLabel = uilabel(app.UIFigure);
            app.DataExtractedFromOscilloscopeLabel.Text = 'Data Extracted From Oscilloscope ';
            app.FFTforfilteroptionalseeStep15Label = uilabel(app.UIFigure);
            app.FFTforfilteroptionalseeStep15Label.Text = 'FFT for filter(optional, see Step 1.5) ';
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result:';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.KnownIssueTextAreaLabel = uilabel(app.UIFigure);
            app.KnownIssueTextAreaLabel.Text = 'Known Issue';
            app.KnownIssueTextArea = uitextarea(app.UIFigure);
            app.KnownIssueTextArea.Value = {'This has been modified to adapt for Caltech user. '; ''; 'For Caltech use, '; 'ch1 must be trans'; 'ch2 must be mzi. '; ''; 'The panel for channel selection is useless now. '; ''; 'Just make sure on your oscilloscope you have connect ch1 for trans and ch2 for mzi. '; ''; 'Do not touch anything in the folder'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Q_Measurement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end