classdef Q_Measurement_for_Rohde_Schwarz_RTA4004 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        autoplus1CheckBox               matlab.ui.control.CheckBox
        ReadMeTextArea                  matlab.ui.control.TextArea
        ReadMeTextAreaLabel             matlab.ui.control.Label
        ResultTextArea                  matlab.ui.control.TextArea
        ResultTextAreaLabel             matlab.ui.control.Label
        FFTforfilteroptionalseeStep15Label  matlab.ui.control.Label
        DataExtractedFromOscilloscopeLabel  matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        Step0OutputFilePathPanel        matlab.ui.container.Panel
        Sub2EditField                   matlab.ui.control.EditField
        Sub2EditFieldLabel              matlab.ui.control.Label
        Sub1EditField                   matlab.ui.control.EditField
        Sub1EditFieldLabel              matlab.ui.control.Label
        FolderPathEditField             matlab.ui.control.EditField
        FolderPathEditFieldLabel        matlab.ui.control.Label
        Step15FFTyourdataoptionalPanel  matlab.ui.container.Panel
        UseFFTdataforQCheckBox          matlab.ui.control.CheckBox
        CaptureButton                   matlab.ui.control.Button
        DisplayedCapturedDataButton     matlab.ui.control.Button
        UpperEditField                  matlab.ui.control.NumericEditField
        UpperEditFieldLabel             matlab.ui.control.Label
        LowerEditField                  matlab.ui.control.NumericEditField
        LowerEditFieldLabel             matlab.ui.control.Label
        IFFTButton                      matlab.ui.control.Button
        FFTButton                       matlab.ui.control.Button
        Step6Cutoff_freqPanel           matlab.ui.container.Panel
        UnithzLabel_2                   matlab.ui.control.Label
        CutoffFrequencyEditField        matlab.ui.control.NumericEditField
        CutoffFrequencyEditFieldLabel   matlab.ui.control.Label
        Step5Corr_typePanel             matlab.ui.container.Panel
        CorrectionDropDown              matlab.ui.control.DropDown
        CorrectionDropDownLabel         matlab.ui.control.Label
        Step4ThresholdPanel             matlab.ui.container.Panel
        thresholdEditField              matlab.ui.control.NumericEditField
        thresholdEditFieldLabel         matlab.ui.control.Label
        Step3SetWavelengthPanel         matlab.ui.container.Panel
        UnitnmLabel                     matlab.ui.control.Label
        lambdaEditField                 matlab.ui.control.NumericEditField
        lambdaEditFieldLabel            matlab.ui.control.Label
        Step2TelltheFSRofyourMZIPanel   matlab.ui.container.Panel
        Mhzis106hzLabel                 matlab.ui.control.Label
        UnitMhzLabel                    matlab.ui.control.Label
        FSREditField                    matlab.ui.control.NumericEditField
        FSREditFieldLabel               matlab.ui.control.Label
        Step1DetermineChannelPanel      matlab.ui.container.Panel
        MZIDropDown                     matlab.ui.control.DropDown
        MZIDropDownLabel                matlab.ui.control.Label
        TransDropDown                   matlab.ui.control.DropDown
        TransDropDownLabel              matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
        Filter_plot                     matlab.ui.control.UIAxes
        FFT                             matlab.ui.control.UIAxes
        RawMzi                          matlab.ui.control.UIAxes
        RawTrans                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        trans %raw trans data
        mzi %raw mzi data
        phi %FFT y-data
        f   %FFT x-data
        clean
        osc %your oscilloscope
    end
    
    methods (Access = private)     
        function [W_shifted, f] = transform(app,trans, samplerate)
             % Check if samplerate is provided, if not, use default 2e9
            if nargin < 3
                samplerate = 2e9; % Default sampling rate
            end
            Fs = samplerate; % Sampling rate in Hz
            N = length(trans); % Number of points in your signal
            df = Fs / N; % Frequency resolution
            f = (-N/2:N/2-1) * df; % Frequency vector centered at 0
            W = fft(trans);
            W_shifted = fftshift(W); % Shift the zero-frequency component to the center of the spectrum
        end
        
        function [x,y] = Rhode_Read_Waveform(app,channel)
            %Configure the setup
            ipAddress = '169.254.218.202';  
            port = 5025;  
            scope = tcpclient(ipAddress, port);
            scope.Timeout = 8;  
            scope.ByteOrder = 'little-endian';
            %Open commuication 
            fopen(scope);
        
            %Get the header for the selected channel 
            %This is used to determine the buffersize
            content = sprintf('CHAN%d:DATA:HEAD?',channel);
            disp(content);
            fprintf(scope, content);

            header = fscanf(scope);
            disp(['Header: ', header]);
        
            % Parse header to determine record length (number of samples)
            headerValues = sscanf(header, '%f,%f,%f,%f');
            recordLength = headerValues(3);
            
            set(scope, 'InputBufferSize', recordLength * 4);
        
            % Read the waveform data corresponding to the displayed data
            content = sprintf('CHAN%d:DATA?',channel);
            fprintf(scope, content);
        
            y = fread(scope,recordLength , 'float32');  % Read the data as 4-byte floating point numbers
            x = linspace(headerValues(1), headerValues(2), headerValues(3));
        
            delete(scope);
            clear scope
        end
        
        function [x2,y2,x3,y3] = Rhode_Read_CH2_CH3(app)

    % Configure the setup
    ipAddress = '169.254.218.202';
    port = 5025;

    scope = tcpclient(ipAddress, port);
    scope.Timeout = 8;
    scope.ByteOrder = 'little-endian';

    fopen(scope);

    %% Capture one complete acquisition
    fprintf(scope, 'SING');
    fprintf(scope, '*OPC?');
    fscanf(scope);

    %% =========================
    % Read CH2
    % =========================
    fprintf(scope, 'CHAN2:DATA:HEAD?');
    header2 = fscanf(scope);
    disp(['CH2 Header: ', header2]);

    headerValues2 = sscanf(header2, '%f,%f,%f,%f');
    recordLength2 = headerValues2(3);

    % IMPORTANT: same as your original code
    set(scope, 'InputBufferSize', recordLength2 * 4);

    fprintf(scope, 'CHAN2:DATA?');

    y2 = fread(scope, recordLength2, 'float32');

    x2 = linspace( ...
        headerValues2(1), ...
        headerValues2(2), ...
        headerValues2(3)).';


    %% =========================
    % Read CH3
    % =========================
    fprintf(scope, 'CHAN3:DATA:HEAD?');
    header3 = fscanf(scope);
    disp(['CH3 Header: ', header3]);

    headerValues3 = sscanf(header3, '%f,%f,%f,%f');
    recordLength3 = headerValues3(3);

    % Resize buffer again for CH3
    set(scope, 'InputBufferSize', recordLength3 * 4);

    fprintf(scope, 'CHAN3:DATA?');

    y3 = fread(scope, recordLength3, 'float32');

    x3 = linspace( ...
        headerValues3(1), ...
        headerValues3(2), ...
        headerValues3(3)).';


    %% Show dimensions
    fprintf('Dimensions of CH2: %d x %d\n', size(y2,1), size(y2,2));
    fprintf('Dimensions of CH3: %d x %d\n', size(y3,1), size(y3,2));


    %% Resume oscilloscope
    fprintf(scope, 'RUN');

    delete(scope);
    clear scope

end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Test the connection to Rhode&Schwarz RTA4004
            %Configure the setup
            ipAddress = '169.254.218.202';  
            port = 5025;  
            scope = tcpclient(ipAddress, port);
            scope.Timeout = 5;  
            scope.ByteOrder = 'little-endian';
            addpath('.');%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            try
                fopen(scope);
                fprintf(scope, '*IDN?');
                idnResponse = fscanf(scope);
                app.ResultTextArea.Value = [app.ResultTextArea.Value; {sprintf('Successfully Connected, Device ID: %s',idnResponse)}];
                delete(scope);
                clear scope;
            catch ME
                % If there is an error (e.g., connection fails), print 'Not Connected'
                app.ResultTextArea.Value = [app.ResultTextArea.Value; {'Failed to connect'}];
            end
        end

        % Button pushed function: DisplayedCapturedDataButton
        function DisplayCaptured(app, event)

            
            %Capture Data 
            [w,e] = Rhode_Read_Waveform(app,channel);
            [a,w,b,e] = Rhode_Read_CH2_CH3(app)

            app.trans = w;
            app.mzi = e;
            % Plot 'trans' on app.RawTrans axes
            plot(app.RawTrans, app.trans);
            
            ylabel(app.RawTrans, 'Voltage');
            title(app.RawTrans, 'Trans');
            
            % Plot 'mzi' on app.RawMzi axes
            plot(app.RawMzi, app.mzi);
            xlabel(app.RawMzi, 'Time');
            ylabel(app.RawMzi, 'Voltage');
            title(app.RawMzi, 'MZI');
        end

        % Button pushed function: FFTButton
        function fourier(app, event)

            if isempty(app.trans)
                app.ResultTextArea.Value = [app.ResultTextArea.Value; {'Press Display'}];
                return 
            end

            % Perform FFT
            [app.phi,  app.f] = app.transform(app.trans); % Call your FFT function, assuming 'w' is your signal
            
            % Plot the Fourier Transform result on the designated UI Axes (app.FFT)
            plot(app.FFT, app.f, abs(app.phi));
            title(app.FFT, 'Frequency Domain');
            xlabel(app.FFT, 'Frequency (Hz)');
            ylabel(app.FFT, 'Magnitude');
             
            %adjust the size of the figure displayed
            app.FFT.XLim = [0.03*min(app.f) , 0.03*max(app.f) ];
            % Only imposed a y min
            currentYLim = app.FFT.YLim; % This gives you a 2-element vector [ymin, ymax]
            currentYMax = currentYLim(2);
            % Now, set the YLim property with your desired ymin and the retrieved ymax
            app.FFT.YLim = [-0.1*currentYMax, currentYMax];
        end

        % Button pushed function: IFFTButton
        function inverseFFT(app, event)
            
            temp = app.phi;
            
            lower = app.LowerEditField.Value;
            upper = app.UpperEditField.Value;

            temp(app.f > upper | app.f<lower) = 0;

            app.clean=ifft(ifftshift(temp), 'symmetric');

            cleandata = app.clean;
            %save('cleaned_app.mat','cleandata');

            plot(app.Filter_plot, app.trans, 'b-', 'LineWidth', 1.5); % Plot 'app.w'
            hold(app.Filter_plot, 'on'); % Hold on for multiple plots
            plot(app.Filter_plot, cleandata, 'r--', 'LineWidth', 1.5); % Plot 'app.clean'
            
            % Setting the legend, labels, and title
            legend(app.Filter_plot, 'Raw Data', 'Filtered Data', 'Location', 'best');
            xlabel(app.Filter_plot, 'sample');
            ylabel(app.Filter_plot, 'voltage');
           
            % Grid and font size adjustment
            grid(app.Filter_plot, 'on');
            set(app.Filter_plot, 'FontSize', 12);
            hold(app.Filter_plot, 'off'); 

        end

        % Button pushed function: RunButton
        function run(app, event)
        %%%
        %this function will
        %connect the osc
        %capture the data
        %fit the capture data using Q_trace_fit class
        %save it to the designated path
        %%%

    % Connect only for acquisition control
    ipAddress = '169.254.218.202';
    port = 5025;

    scope = tcpclient(ipAddress, port);
    scope.Timeout = 8;

    %% Acquire one complete synchronized trace
    fprintf(scope, 'SING');
    fprintf(scope, '*OPC?');
    fscanf(scope);

    delete(scope);
    clear scope

    %% Read using YOUR ORIGINAL FUNCTION
    [~,w] = app.Rhode_Read_Waveform( ...
        str2double(app.TransDropDown.Value));

    [~,e] = app.Rhode_Read_Waveform( ...
        str2double(app.MZIDropDown.Value));

    %% Resume oscilloscope
    scope = tcpclient(ipAddress, port);
    scope.Timeout = 8;

    fprintf(scope, 'RUN');

    delete(scope);
    clear scope

    %% Check
    fprintf('Dimensions of w: %d %d\n', size(w,1), size(w,2));
    fprintf('Dimensions of e: %d %d\n', size(e,1), size(e,2));
        % Display the dimensions of w
        disp(['Dimensions of w: ', num2str(size(w))]);
        
        % Display the dimensions of e
        disp(['Dimensions of e: ', num2str(size(e))]);

        %Prepare the Q fitting parameters
        deg1 = app.Sub1EditField.Value;
        deg2 = app.Sub2EditField.Value;
        basePath=app.FolderPathEditField.Value; 
        MZI_FSR = app.FSREditField.Value;
        lambda = app.lambdaEditField.Value; 
        threshold= app.thresholdEditField.Value ; 
        corr_type= app.CorrectionDropDown.Value;
        cutoff_freq= app.CutoffFrequencyEditField.Value;

        %{
        transData = load("C:\Users\Eric\Downloads\Q_keysight\KeySight DSOX1204G\todaytrans.mat");
        mzicaltechData = load("C:\Users\Eric\Downloads\Q_keysight\KeySight DSOX1204G\mzitoday(caltech).mat");
        w = transData.w'; 
        e = mzicaltechData.e';
        %}
        corr_type_brother = corr_type;
        if strcmp(corr_type_brother, ' ')
            % Assuming you want to default to a generic name or handle differently
            corr_type_brother = 'no_correction';
        end
        

        %Create saving location and save the raw data first
        jack = sprintf('data_%s',corr_type_brother );
        inherent = fullfile(basePath, deg1,deg2,jack);
        if ~exist(inherent, 'dir')
            mkdir(inherent);
        end
        mziname = sprintf('osc_mzi_%.2fMHz.mat', MZI_FSR);
        save(fullfile(inherent,'osc_trans.mat'),'w');
        save(fullfile(inherent,mziname),'e');

        %Clear the displaying axes
        cla(app.RawTrans, 'reset');
        cla(app.RawMzi, 'reset');
        cla(app.UIAxes1, 'reset');
        cla(app.UIAxes2, 'reset');
        cla(app.UIAxes3, 'reset');

        % Plot 'trans' on app.RawTrans axes
        plot(app.RawTrans, w);
        
        ylabel(app.RawTrans, 'Voltage');
        title(app.RawTrans, 'Trans');
        
        % Plot 'mzi' on app.RawMzi axes
        plot(app.RawMzi, e);
        xlabel(app.RawMzi, 'Sample');
        ylabel(app.RawMzi, 'Voltage');
        title(app.RawMzi, 'MZI');

        %Do the Q_fitting
        
        Q = Cao_2(w,e,MZI_FSR,lambda,threshold,corr_type,cutoff_freq);

        %display the result 
        Q.plot_Q(1,app.UIAxes1);
        Q.plot_trace_stat(app.UIAxes2); 
        Q.plot_Q_stat(app.UIAxes3);
        app.UIAxes1.Title.FontSize = 18;

        
        %save it 

        
        folderName = fullfile(basePath, deg1,deg2 );
        if ~exist(folderName, 'dir')
            mkdir(folderName);
        end
        
        % Filename base for dynamic naming
        filenameBase = 'Q';
        
        % Temporarily generate and save figure for plot_Q with dynamic filename
        figQTemp = figure;
        axQTemp = axes(figQTemp);
        Q.plot_Q(1, axQTemp); % Ensure plot_Q can work with standard axes
        dynamicFilename = sprintf('%s;%s_lambda=%.2fnm_0=%.4gM', deg2, filenameBase, lambda, Q.modeQ0(1));
        fullFilePathQ = fullfile(fullfile(basePath, deg1), [dynamicFilename, '.fig']); % Full file path
        saveas(figQTemp, fullFilePathQ); % Save the figure
        close(figQTemp); % Close the temporary figure
        
        % Temporarily generate and save figure for plot_trace_stat
        figTraceTemp = figure;
        axTraceTemp = axes(figTraceTemp);
        Q.plot_trace_stat(axTraceTemp); % Ensure plot_trace_stat can work with standard axes
        savefig(figTraceTemp, fullfile(folderName, 'Trace.fig')); % Save the figure
        close(figTraceTemp); % Close the temporary figure
        
        % Temporarily generate and save figure for plot_Q_stat
        figQstatTemp = figure;
        axQstatTemp = axes(figQstatTemp);
        Q.plot_Q_stat(axQstatTemp); % Ensure plot_Q_stat can work with standard axes
        savefig(figQstatTemp, fullfile(folderName, 'Qstat.fig')); % Save the figure
        close(figQstatTemp); % Close the temporary figure

        %osc&Q_analysis data
        corr_type = app.CorrectionDropDown.Value;

        % Handle the 'No Correction' case
        if strcmp(corr_type, ' ')
            % Assuming you want to default to a generic name or handle differently
            corr_type = 'no_correction';
        end

        save(fullfile(inherent,'Q_analysis.mat'),'Q');
        %{
        %record the para 
        para = sprintf('Para: %.3f MHz; %.3f nm; %.3f ; %s ; %.3f', MZI_FSR, lambda, threshold, corr_type, cutoff_freq);
        paraname =  fullfile(basePath, deg1,deg2,para);
        if ~exist(paraname, 'dir')
            mkdir(paraname);
        end
        %}

        % Update the app's text area
        info = sprintf('Q0_max = %.4g M, Saved to path: %s', Q.modeQ0(1), folderName);
        app.ResultTextArea.Value = [app.ResultTextArea.Value; {info}];
        %Updated on 2024/8/5:
        %Extract the fano parameters if the user use it
        fano_flag = 0 ;
        if ischar(corr_type) && (strcmp(corr_type, 'fano') || strcmp(corr_type, 'fanomzi'))
             A = Q.A;
            LP = Q.LP;
            LS = Q.LS;
            x0 = Q.x0;
            F0 = Q.F0;
            ratio = F0/LS;
            info = sprintf('Fano Fitting Parameters: A=%.4f, LP=%.4f, LS=%.4f, x0=%.4f, F0=%.4f,F0/LS =%.4f, Q0_max = %.4g M, Saved to path: %s', ...
            A, LP, LS, x0, F0,ratio,Q.modeQ0(1), folderName);
          
            fano_flag = 1;
        else
            % Update the app's text area
            info = sprintf('Q0_max = %.4g M, Saved to path: %s', ...
                Q.modeQ0(1), folderName);
        end
        app.ResultTextArea.Value = [app.ResultTextArea.Value; {info}];

        if fano_flag
            %save the parameters as a txt file
            txt_file = fullfile(folderName,'fano_parameters.txt');
            fileID = fopen(txt_file, 'w');
            fprintf(fileID, 'A=%.10f \n', A);
            fprintf(fileID, 'LP=%.10f \n', LP);
            fprintf(fileID, 'LS=%.10f \n', LS);
            fprintf(fileID, 'x0=%.10f \n', x0);
            fprintf(fileID, 'F0=%.10f \n', F0);
            fprintf(fileID,'F0/LS=%.10f \n',ratio);
            fclose(fileID);
            app.ResultTextArea.Value = [app.ResultTextArea.Value; {'Fano parameters are saved to the same path above.'}];
        end
        % plus 1
        % Get the current value from app.Sub2EditField (assuming it's a string representation of a number)
        currentValueStr = app.Sub2EditField.Value;
        
        % Convert the string to a number (assuming it represents an integer)
        currentValue = str2double(currentValueStr);
        
        % Check if app.autoplus1CheckBox is checked
        if app.autoplus1CheckBox.Value == true
            % Increment the numeric value
            newValue = currentValue + 1;
            
            % Convert the new value back to a string
            newValueStr = num2str(newValue);
            
            % Update app.Sub2EditField with the new string value
            app.Sub2EditField.Value = newValueStr;
        end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RawTrans = uiaxes(app.UIFigure);
            title(app.RawTrans, 'Trans', 'Interpreter', 'tex')
            ylabel(app.RawTrans, 'voltage', 'Interpreter', 'tex')
            zlabel(app.RawTrans, 'Z', 'Interpreter', 'tex')
            app.RawMzi = uiaxes(app.UIFigure);
            title(app.RawMzi, 'MZI', 'Interpreter', 'tex')
            xlabel(app.RawMzi, 'sample', 'Interpreter', 'tex')
            ylabel(app.RawMzi, 'voltage', 'Interpreter', 'tex')
            zlabel(app.RawMzi, 'Z', 'Interpreter', 'tex')
            app.FFT = uiaxes(app.UIFigure);
            title(app.FFT, 'frequency domain', 'Interpreter', 'tex')
            xlabel(app.FFT, 'f', 'Interpreter', 'tex')
            ylabel(app.FFT, 'voltage', 'Interpreter', 'tex')
            zlabel(app.FFT, 'Z', 'Interpreter', 'tex')
            app.Filter_plot = uiaxes(app.UIFigure);
            title(app.Filter_plot, 'Filtered Trans', 'Interpreter', 'tex')
            xlabel(app.Filter_plot, 'X', 'Interpreter', 'tex')
            ylabel(app.Filter_plot, 'Y', 'Interpreter', 'tex')
            zlabel(app.Filter_plot, 'Z', 'Interpreter', 'tex')
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Title', 'Interpreter', 'tex')
            xlabel(app.UIAxes1, 'X', 'Interpreter', 'tex')
            ylabel(app.UIAxes1, 'Y', 'Interpreter', 'tex')
            zlabel(app.UIAxes1, 'Z', 'Interpreter', 'tex')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title', 'Interpreter', 'tex')
            xlabel(app.UIAxes2, 'X', 'Interpreter', 'tex')
            ylabel(app.UIAxes2, 'Y', 'Interpreter', 'tex')
            zlabel(app.UIAxes2, 'Z', 'Interpreter', 'tex')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title', 'Interpreter', 'tex')
            xlabel(app.UIAxes3, 'X', 'Interpreter', 'tex')
            ylabel(app.UIAxes3, 'Y', 'Interpreter', 'tex')
            zlabel(app.UIAxes3, 'Z', 'Interpreter', 'tex')
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Rhode Schwarz RTA4004: Single Cap 450nm';
            app.Step1DetermineChannelPanel = uipanel(app.UIFigure);
            app.Step1DetermineChannelPanel.Title = 'Step 1: Determine Channel';
            app.TransDropDownLabel = uilabel(app.Step1DetermineChannelPanel);
            app.TransDropDownLabel.Text = 'Trans';
            app.TransDropDown = uidropdown(app.Step1DetermineChannelPanel);
            app.TransDropDown.Items = {'CH 1', 'CH 2', 'CH 3', 'CH 4'};
            app.TransDropDown.ItemsData = {'1', '2', '3', '4'};
            app.TransDropDown.Value = '2';
            app.MZIDropDownLabel = uilabel(app.Step1DetermineChannelPanel);
            app.MZIDropDownLabel.Text = 'MZI';
            app.MZIDropDown = uidropdown(app.Step1DetermineChannelPanel);
            app.MZIDropDown.Items = {'CH 1', 'CH 2', 'CH 3', 'CH 4'};
            app.MZIDropDown.ItemsData = {'1', '2', '3', '4'};
            app.MZIDropDown.Value = '3';
            app.Step2TelltheFSRofyourMZIPanel = uipanel(app.UIFigure);
            app.Step2TelltheFSRofyourMZIPanel.Title = 'Step 2: Tell the FSR of your MZI';
            app.FSREditFieldLabel = uilabel(app.Step2TelltheFSRofyourMZIPanel);
            app.FSREditFieldLabel.Text = 'FSR ';
            app.FSREditField = uieditfield(app.Step2TelltheFSRofyourMZIPanel, 'numeric');
            app.FSREditField.Value = 11.85;
            app.UnitMhzLabel = uilabel(app.Step2TelltheFSRofyourMZIPanel);
            app.UnitMhzLabel.Text = 'Unit: Mhz';
            app.Mhzis106hzLabel = uilabel(app.Step2TelltheFSRofyourMZIPanel);
            app.Mhzis106hzLabel.Text = 'Mhz is 10^6 hz';
            app.Step3SetWavelengthPanel = uipanel(app.UIFigure);
            app.Step3SetWavelengthPanel.Title = 'Step 3: Set Wavelength';
            app.lambdaEditFieldLabel = uilabel(app.Step3SetWavelengthPanel);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.Step3SetWavelengthPanel, 'numeric');
            app.lambdaEditField.Value = 976;
            app.UnitnmLabel = uilabel(app.Step3SetWavelengthPanel);
            app.UnitnmLabel.Text = 'Unit: nm';
            app.Step4ThresholdPanel = uipanel(app.UIFigure);
            app.Step4ThresholdPanel.Title = 'Step 4: Threshold';
            app.thresholdEditFieldLabel = uilabel(app.Step4ThresholdPanel);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.Step4ThresholdPanel, 'numeric');
            app.thresholdEditField.Value = 0.95;
            app.Step5Corr_typePanel = uipanel(app.UIFigure);
            app.Step5Corr_typePanel.Title = 'Step 5: Corr_type';
            app.CorrectionDropDownLabel = uilabel(app.Step5Corr_typePanel);
            app.CorrectionDropDownLabel.Text = 'Correction';
            app.CorrectionDropDown = uidropdown(app.Step5Corr_typePanel);
            app.CorrectionDropDown.Items = {'No Correction', 'fano', 'split', 'oscmzi', 'mzi', 'fanomzi', 'splitmzi', 'osc'};
            app.CorrectionDropDown.ItemsData = {' ', 'fano', 'split', 'oscmzi', 'mzi', 'fanomzi', 'splitmzi', 'osc'};
            app.CorrectionDropDown.Value = ' ';
            app.Step6Cutoff_freqPanel = uipanel(app.UIFigure);
            app.Step6Cutoff_freqPanel.Title = 'Step 6: Cutoff_freq';
            app.CutoffFrequencyEditFieldLabel = uilabel(app.Step6Cutoff_freqPanel);
            app.CutoffFrequencyEditFieldLabel.Text = 'Cutoff Frequency';
            app.CutoffFrequencyEditField = uieditfield(app.Step6Cutoff_freqPanel, 'numeric');
            app.UnithzLabel_2 = uilabel(app.Step6Cutoff_freqPanel);
            app.UnithzLabel_2.Text = 'Unit: hz';
            app.Step15FFTyourdataoptionalPanel = uipanel(app.UIFigure);
            app.Step15FFTyourdataoptionalPanel.Title = 'Step 1.5: FFT your data(optional)';
            app.FFTButton = uibutton(app.Step15FFTyourdataoptionalPanel, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @fourier, true);
            app.FFTButton.Text = 'FFT';
            app.IFFTButton = uibutton(app.Step15FFTyourdataoptionalPanel, 'push');
            app.IFFTButton.ButtonPushedFcn = createCallbackFcn(app, @inverseFFT, true);
            app.IFFTButton.Text = 'IFFT';
            app.LowerEditFieldLabel = uilabel(app.Step15FFTyourdataoptionalPanel);
            app.LowerEditFieldLabel.Text = 'Lower ';
            app.LowerEditField = uieditfield(app.Step15FFTyourdataoptionalPanel, 'numeric');
            app.LowerEditField.Value = -100000;
            app.UpperEditFieldLabel = uilabel(app.Step15FFTyourdataoptionalPanel);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.Step15FFTyourdataoptionalPanel, 'numeric');
            app.UpperEditField.Value = 100000;
            app.DisplayedCapturedDataButton = uibutton(app.Step15FFTyourdataoptionalPanel, 'push');
            app.DisplayedCapturedDataButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayCaptured, true);
            app.DisplayedCapturedDataButton.Text = 'Displayed Captured Data';
            app.CaptureButton = uibutton(app.Step15FFTyourdataoptionalPanel, 'push');
            app.CaptureButton.Text = 'Capture';
            app.UseFFTdataforQCheckBox = uicheckbox(app.Step15FFTyourdataoptionalPanel);
            app.UseFFTdataforQCheckBox.Text = 'Use FFT data for Q';
            app.Step0OutputFilePathPanel = uipanel(app.UIFigure);
            app.Step0OutputFilePathPanel.Title = 'Step 0: Output File Path';
            app.FolderPathEditFieldLabel = uilabel(app.Step0OutputFilePathPanel);
            app.FolderPathEditFieldLabel.Text = 'Folder Path';
            app.FolderPathEditField = uieditfield(app.Step0OutputFilePathPanel, 'text');
            app.FolderPathEditField.Value = 'C:\Users\Dirk\Documents\Q_Measurements';
            app.Sub1EditFieldLabel = uilabel(app.Step0OutputFilePathPanel);
            app.Sub1EditFieldLabel.Text = 'Sub1';
            app.Sub1EditField = uieditfield(app.Step0OutputFilePathPanel, 'text');
            app.Sub1EditField.Value = 'dirk';
            app.Sub2EditFieldLabel = uilabel(app.Step0OutputFilePathPanel);
            app.Sub2EditFieldLabel.Text = 'Sub2';
            app.Sub2EditField = uieditfield(app.Step0OutputFilePathPanel, 'text');
            app.Sub2EditField.Value = 'bouwmeester';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @run, true);
            app.RunButton.Text = 'Run';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '';
            app.DataExtractedFromOscilloscopeLabel = uilabel(app.UIFigure);
            app.DataExtractedFromOscilloscopeLabel.Text = 'Data Extracted From Oscilloscope ';
            app.FFTforfilteroptionalseeStep15Label = uilabel(app.UIFigure);
            app.FFTforfilteroptionalseeStep15Label.Text = 'FFT for filter(optional, see Step 1.5) ';
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result:';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.ReadMeTextAreaLabel = uilabel(app.UIFigure);
            app.ReadMeTextAreaLabel.Text = 'Read Me';
            app.ReadMeTextArea = uitextarea(app.UIFigure);
            app.ReadMeTextArea.Value = {'Rohde&Schwarz RTA4004, Ethernet Connect'; ''; ''; 'It is updated on 2026/08/25. When we used it to scan toptica 450nm, at 0.6hz scan rate, the usual code will experience an unequal return of list elements from mzi and trans. This version solves it by replacing the regular acquisition with a single acquistion. it will set osc back to continuous once finished taking the data.'};
            app.autoplus1CheckBox = uicheckbox(app.UIFigure);
            app.autoplus1CheckBox.Text = 'auto plus 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Q_Measurement_for_Rohde_Schwarz_RTA4004

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end